classdef calibrate_insoles < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		CalibrateInsolesUIFigure    matlab.ui.Figure
		ReverseLRInsolesCheckBox    matlab.ui.control.CheckBox
		RightAdjusttimeintervaltouseCheckBox  matlab.ui.control.CheckBox
		LeftAdjusttimeintervaltouseCheckBox  matlab.ui.control.CheckBox
		RightInsoleForcePlateButtonGroup  matlab.ui.container.ButtonGroup
		RightInsoleFP2Button        matlab.ui.control.RadioButton
		RightInsoleFP1Button        matlab.ui.control.RadioButton
		LeftInsoleForcePlateButtonGroup  matlab.ui.container.ButtonGroup
		LeftInsoleFP2Button         matlab.ui.control.RadioButton
		LeftInsoleFP1Button         matlab.ui.control.RadioButton
		SavedCoefficientsEditField  matlab.ui.control.EditField
		SavedCoefficientsEditFieldLabel  matlab.ui.control.Label
		SaveButton                  matlab.ui.control.Button
		Coeff_1_RightEditField      matlab.ui.control.NumericEditField
		LeftCoefficientsLabel_2     matlab.ui.control.Label
		Coeff_2_RightEditField      matlab.ui.control.NumericEditField
		Coeff_2_LeftEditField       matlab.ui.control.NumericEditField
		Coeff_1_LeftEditField       matlab.ui.control.NumericEditField
		LeftCoefficientsLabel       matlab.ui.control.Label
		appversionLabel             matlab.ui.control.Label
		SelectViconCsvButton        matlab.ui.control.Button
		ViconCsvEditField           matlab.ui.control.EditField
		ViconcsvEditFieldLabel      matlab.ui.control.Label
		UIAxesDataRight             matlab.ui.control.UIAxes
		UIAxesDataLeft              matlab.ui.control.UIAxes
	end


	properties (Access = private)
		Property % Description
	end

	properties (Access = public)
		vicon_data                 % data from vicon csv file
		coeff_left                 % linear fit coeff for left
		coeff_right                % linear fit coeff for right
		vicon_data_unreversed	   % hold a copy of the original data when reversing left & right insoles
	end

	methods (Access = private)

	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			app.appversionLabel.Text = 'calibrate_insoles.mlapp - version 0.3 2024-07-26';
		end

		% Button pushed function: SelectViconCsvButton
		function SelectViconCsvButtonPushed(app, event)
			ML_W_switch('ml');
			msg = 'Pick Vicon CSV File with Insole and Force Plate Data';
			disp(msg)
			[fname, pname] = uigetfile('*.csv', 'Pick Vicon CSV File');
			if ~isequal(fname,0) && ~isequal(pname,0)
				app.ViconCsvEditField.Value = fullfile(pname, fname);
				ViconCsvEditFieldValueChanged(app)
			end
		end

		% Value changed function: ViconCsvEditField
		function ViconCsvEditFieldValueChanged(app, event)
			fname = app.ViconCsvEditField.Value;
			% read in the data
			dlg = uiprogressdlg(app.CalibrateInsolesUIFigure, 'Title', 'Reading File',  ...
				'Message', fname, 'Indeterminate', 'on');
			if contains(lower(fname), '_devices')
				app.vicon_data.devices = read_vicon_devices_csv(fname);
			else
				app.vicon_data = read_vicon_csv(fname);
			end

			% if l-r reversed checkbox
			if app.ReverseLRInsolesCheckBox.Value == 1
				reverse_left_right_insoles(app)
			end

			% reset axes
			reset_cal_gui(app)

			close (dlg)

			% display the data
			cal_display_data(app)

			% compute linear fit between FSR V & FP N
			cal_compute_calibration(app)

			% show fit
			cal_show_fit(app)

		end

		% Button pushed function: SaveButton
		function SaveButtonPushed(app, event)
			% use vicon data path to determine analysis save folder
			[save_loc, ~, ~] = fileparts(app.vicon_data.filename);
			% if the current directory has '/data/' in it then change it
			% '/analysis/' to save the output there
			if contains(save_loc, [filesep 'data' filesep], 'IgnoreCase', true)
				save_loc = strrep(lower(save_loc), [filesep 'data' filesep], [filesep 'analysis' filesep]);
				% ask to create the folder if it doesn't exist
				if ~exist(save_loc, 'dir')
					ButtonName = questdlg(['Create new directory: ' save_loc ' ?'], ...
						'Create new directory', ...
						'Yes', 'No', 'Yes');
					if strcmp(ButtonName, 'Yes')
						[success, msg, msg_id] = mkdir(save_loc); %#ok<ASGLU>
					else
						disp('Choose where to save output')
						save_loc = uigetdir();
					end
				end
			end

			if ~exist(save_loc, 'dir')
				disp('no coefficients being saved')
				return
			end

			app.SavedCoefficientsEditField.Value = save_loc;

			% file name to save
			fname = fullfile(save_loc, 'insole_cal_coeffs.xml');
			[confirm_saving, fname] = confirm_savename(fname);
			if confirm_saving
				coeffs.left = app.coeff_left;
				coeffs.right = app.coeff_right;
				if app.ReverseLRInsolesCheckBox.Value == 1
					coeffs.lr_reversed = true;
				end
				writestruct(coeffs, fname)
			end
		end

		% Selection changed function: LeftInsoleForcePlateButtonGroup
		function LeftInsoleForcePlateButtonGroupSelectionChanged(app, event)
			% 			selectedButton = app.LeftInsoleForcePlateButtonGroup.SelectedObject;
			% change force plate data being shown
			cal_show_fp(app, app.UIAxesDataLeft, 'left')
			% compute linear fit between FSR V & FP N
			app.coeff_left = cal_compute_fit_coef(app, 'left');
			app.Coeff_1_LeftEditField.Value = app.coeff_left(1);
			app.Coeff_2_LeftEditField.Value = app.coeff_left(2);
			% show fit
			cal_show_fsr_force_est(app, 'left')
		end

		% Selection changed function: RightInsoleForcePlateButtonGroup
		function RightInsoleForcePlateButtonGroupSelectionChanged(app, event)
			% 			selectedButton = app.RightInsoleForcePlateButtonGroup.SelectedObject;
			% change force plate data being shown
			cal_show_fp(app, app.UIAxesDataRight, 'right')
			% compute linear fit between FSR V & FP N
			app.coeff_right = cal_compute_fit_coef(app, 'right');
			app.Coeff_1_RightEditField.Value = app.coeff_right(1);
			app.Coeff_2_RightEditField.Value = app.coeff_right(2);
			% show fit
			cal_show_fsr_force_est(app, 'right')
		end

		% Value changed function: LeftAdjusttimeintervaltouseCheckBox
		function LeftAdjusttimeintervaltouseCheckBoxValueChanged(app, event)
			value = app.LeftAdjusttimeintervaltouseCheckBox.Value;
			if value == 1
				cal_add_interval(app, 'left')
			else
				cal_remove_interval(app, 'left')
			end
		end

		% Value changed function: RightAdjusttimeintervaltouseCheckBox
		function RightAdjusttimeintervaltouseCheckBoxValueChanged(app, event)
			value = app.RightAdjusttimeintervaltouseCheckBox.Value;
			if value == 1
				cal_add_interval(app, 'right')
			else
				cal_remove_interval(app, 'right')
			end
		end

		% Value changed function: ReverseLRInsolesCheckBox
		function ReverseLRInsolesCheckBoxValueChanged(app, event)
			if isempty(app.vicon_data)
				return
			end
			value = app.ReverseLRInsolesCheckBox.Value;
			if value == 1
				% reverse Left and right insoles
				reverse_left_right_insoles(app)
			else
				un_reverse_left_right_insoles(app)
			end
			% reset axes
			reset_cal_gui(app)

			% display the data
			cal_display_data(app)

			% compute linear fit between FSR V & FP N
			cal_compute_calibration(app)

			% show fit
			cal_show_fit(app)
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.CalibrateInsolesUIFigure = uifigure('Visible', 'off');
			app.CalibrateInsolesUIFigure.Name = 'Calibrate Insoles';
			app.UIAxesDataLeft = uiaxes(app.CalibrateInsolesUIFigure);
			title(app.UIAxesDataLeft, 'Left Insole & FP')
			xlabel(app.UIAxesDataLeft, 'Time (s)')
			ylabel(app.UIAxesDataLeft, 'Y')
			zlabel(app.UIAxesDataLeft, 'Z')
			app.UIAxesDataRight = uiaxes(app.CalibrateInsolesUIFigure);
			title(app.UIAxesDataRight, 'Right Insole & FP')
			xlabel(app.UIAxesDataRight, 'Time (s)')
			ylabel(app.UIAxesDataRight, 'Y')
			zlabel(app.UIAxesDataRight, 'Z')
			app.ViconcsvEditFieldLabel = uilabel(app.CalibrateInsolesUIFigure);
			app.ViconcsvEditFieldLabel.Text = 'Vicon csv:';
			app.ViconCsvEditField = uieditfield(app.CalibrateInsolesUIFigure, 'text');
			app.ViconCsvEditField.ValueChangedFcn = createCallbackFcn(app, @ViconCsvEditFieldValueChanged, true);
			app.SelectViconCsvButton = uibutton(app.CalibrateInsolesUIFigure, 'push');
			app.SelectViconCsvButton.ButtonPushedFcn = createCallbackFcn(app, @SelectViconCsvButtonPushed, true);
			app.SelectViconCsvButton.Text = 'Select';
			app.appversionLabel = uilabel(app.CalibrateInsolesUIFigure);
			app.appversionLabel.Text = 'app version';
			app.LeftCoefficientsLabel = uilabel(app.CalibrateInsolesUIFigure);
			app.LeftCoefficientsLabel.Text = 'Left Coefficients';
			app.Coeff_1_LeftEditField = uieditfield(app.CalibrateInsolesUIFigure, 'numeric');
			app.Coeff_1_LeftEditField.Editable = 'off';
			app.Coeff_2_LeftEditField = uieditfield(app.CalibrateInsolesUIFigure, 'numeric');
			app.Coeff_2_LeftEditField.Editable = 'off';
			app.Coeff_2_RightEditField = uieditfield(app.CalibrateInsolesUIFigure, 'numeric');
			app.Coeff_2_RightEditField.Editable = 'off';
			app.LeftCoefficientsLabel_2 = uilabel(app.CalibrateInsolesUIFigure);
			app.LeftCoefficientsLabel_2.Text = 'Right Coefficients';
			app.Coeff_1_RightEditField = uieditfield(app.CalibrateInsolesUIFigure, 'numeric');
			app.Coeff_1_RightEditField.Editable = 'off';
			app.SaveButton = uibutton(app.CalibrateInsolesUIFigure, 'push');
			app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
			app.SaveButton.Text = 'Save';
			app.SavedCoefficientsEditFieldLabel = uilabel(app.CalibrateInsolesUIFigure);
			app.SavedCoefficientsEditFieldLabel.Text = 'Saved Coefficients:';
			app.SavedCoefficientsEditField = uieditfield(app.CalibrateInsolesUIFigure, 'text');
			app.SavedCoefficientsEditField.Editable = 'off';
			app.LeftInsoleForcePlateButtonGroup = uibuttongroup(app.CalibrateInsolesUIFigure);
			app.LeftInsoleForcePlateButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LeftInsoleForcePlateButtonGroupSelectionChanged, true);
			app.LeftInsoleForcePlateButtonGroup.Title = 'Force Plate';
			app.LeftInsoleFP1Button = uiradiobutton(app.LeftInsoleForcePlateButtonGroup);
			app.LeftInsoleFP1Button.Text = 'FP1';
			app.LeftInsoleFP1Button.Value = true;
			app.LeftInsoleFP2Button = uiradiobutton(app.LeftInsoleForcePlateButtonGroup);
			app.LeftInsoleFP2Button.Text = 'FP2';
			app.RightInsoleForcePlateButtonGroup = uibuttongroup(app.CalibrateInsolesUIFigure);
			app.RightInsoleForcePlateButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RightInsoleForcePlateButtonGroupSelectionChanged, true);
			app.RightInsoleForcePlateButtonGroup.Title = 'Force Plate';
			app.RightInsoleFP1Button = uiradiobutton(app.RightInsoleForcePlateButtonGroup);
			app.RightInsoleFP1Button.Text = 'FP1';
			app.RightInsoleFP2Button = uiradiobutton(app.RightInsoleForcePlateButtonGroup);
			app.RightInsoleFP2Button.Text = 'FP2';
			app.RightInsoleFP2Button.Value = true;
			app.LeftAdjusttimeintervaltouseCheckBox = uicheckbox(app.CalibrateInsolesUIFigure);
			app.LeftAdjusttimeintervaltouseCheckBox.ValueChangedFcn = createCallbackFcn(app, @LeftAdjusttimeintervaltouseCheckBoxValueChanged, true);
			app.LeftAdjusttimeintervaltouseCheckBox.Text = 'Adjust time interval to use';
			app.RightAdjusttimeintervaltouseCheckBox = uicheckbox(app.CalibrateInsolesUIFigure);
			app.RightAdjusttimeintervaltouseCheckBox.ValueChangedFcn = createCallbackFcn(app, @RightAdjusttimeintervaltouseCheckBoxValueChanged, true);
			app.RightAdjusttimeintervaltouseCheckBox.Text = 'Adjust time interval to use';
			app.ReverseLRInsolesCheckBox = uicheckbox(app.CalibrateInsolesUIFigure);
			app.ReverseLRInsolesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReverseLRInsolesCheckBoxValueChanged, true);
			app.ReverseLRInsolesCheckBox.Text = 'Reverse L-R Insoles';
			% Show the figure after all components are created
			app.CalibrateInsolesUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = calibrate_insoles

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.CalibrateInsolesUIFigure)

				% Execute the startup function
				runStartupFcn(app, @startupFcn)
			else

				% Focus the running singleton app
				figure(runningApp.CalibrateInsolesUIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.CalibrateInsolesUIFigure)
		end
	end
end