classdef coordinate_events < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EventCoordinationUIFigure  matlab.ui.Figure
        EEGEditFieldLabel          matlab.ui.control.Label
        EEGEditField               matlab.ui.control.EditField
        NIRSEditFieldLabel         matlab.ui.control.Label
        NIRSEditField              matlab.ui.control.EditField
        ViconEditFieldLabel        matlab.ui.control.Label
        ViconEditField             matlab.ui.control.EditField
        SelectEegButton            matlab.ui.control.Button
        SelectNirsButton           matlab.ui.control.Button
        SelectViconButton          matlab.ui.control.Button
    end

    
    properties (Access = public)
        eeg_data         % EEG data struct read in from vhdr file
        vicon_event_struct % event_struct of vicon hs & to events
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EEGEditField
        function EEGEditFieldValueChanged(app, event)
            value = app.EEGEditField.Value;
            if ~isempty(value)
                [pname, fname, ext] = fileparts(value);
                if ~strcmp(ext, '.vhdr')
	           error('EEG File must be a *.vhdr file');
                end
                % read in eeg file
                app.eeg_data = pop_loadbv(pname, [fname ext]);
            end
        end

        % Value changed function: NIRSEditField
        function NIRSEditFieldValueChanged(app, event)
%             value = app.NIRSEditField.Value;
%             if ~isempty(value)
%                 % read in nirs file
%             end
        end

        % Value changed function: ViconEditField
        function ViconEditFieldValueChanged(app, event)
            value = app.ViconEditField.Value;
            if ~isempty(value)
                % read in vicon events
            end
        end

        % Button pushed function: SelectEegButton
        function SelectEegButtonPushed(app, event)
            disp('Pick EEG Data vhdr File')
            [fname, pname] = uigetfile('*.vhdr', 'Pick EEG Data vhdr File');
            if ~isequal(fname,0) && ~isequal(pname,0)
                app.EEGEditField.Value = fullfile(pname, fname);
                EEGEditFieldValueChanged(app)
            end
        end

        % Button pushed function: SelectViconButton
        function SelectViconButtonPushed(app, event)
            disp('Select Vicon Analysis folder with HS & TO events')
            [fname, pname] = uigetdir('', 'Pick a Vicon Analysis Folder');
            if ~isequal(fname,0) && ~isequal(pname,0)
                app.ViconEditField.Value = fullfile(pname, fname);
                ViconEditFieldValueChanged(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EventCoordinationUIFigure = uifigure('Visible', 'off');
            app.EventCoordinationUIFigure.Name = 'EventCoordination';
            app.EEGEditFieldLabel = uilabel(app.EventCoordinationUIFigure);
            app.EEGEditFieldLabel.Text = 'EEG';
            app.EEGEditField = uieditfield(app.EventCoordinationUIFigure, 'text');
            app.EEGEditField.ValueChangedFcn = createCallbackFcn(app, @EEGEditFieldValueChanged, true);
            app.NIRSEditFieldLabel = uilabel(app.EventCoordinationUIFigure);
            app.NIRSEditFieldLabel.Text = 'NIRS';
            app.NIRSEditField = uieditfield(app.EventCoordinationUIFigure, 'text');
            app.NIRSEditField.ValueChangedFcn = createCallbackFcn(app, @NIRSEditFieldValueChanged, true);
            app.ViconEditFieldLabel = uilabel(app.EventCoordinationUIFigure);
            app.ViconEditFieldLabel.Text = 'Vicon';
            app.ViconEditField = uieditfield(app.EventCoordinationUIFigure, 'text');
            app.ViconEditField.ValueChangedFcn = createCallbackFcn(app, @ViconEditFieldValueChanged, true);
            app.SelectEegButton = uibutton(app.EventCoordinationUIFigure, 'push');
            app.SelectEegButton.ButtonPushedFcn = createCallbackFcn(app, @SelectEegButtonPushed, true);
            app.SelectEegButton.Text = 'Select';
            app.SelectNirsButton = uibutton(app.EventCoordinationUIFigure, 'push');
            app.SelectNirsButton.Text = 'Select';
            app.SelectViconButton = uibutton(app.EventCoordinationUIFigure, 'push');
            app.SelectViconButton.ButtonPushedFcn = createCallbackFcn(app, @SelectViconButtonPushed, true);
            app.SelectViconButton.Text = 'Select';
            % Show the figure after all components are created
            app.EventCoordinationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = coordinate_events

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EventCoordinationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EventCoordinationUIFigure)
        end
    end
end