classdef foot_markers < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		HeelMarkerInsolesUIFigure  matlab.ui.Figure
		SendtoDatabaseButton       matlab.ui.control.Button
		UITable_gc_info            matlab.ui.control.Table
		UIAxes_uninv_insole        matlab.ui.control.UIAxes
		UIAxes_uninv_heel          matlab.ui.control.UIAxes
		UIAxes_inv_insole          matlab.ui.control.UIAxes
		UIAxes_inv_heel            matlab.ui.control.UIAxes
	end


	properties (Access = private)

	end

	properties (Access = public)
		caller_app		% the vicon_insoles app that called this app

		gc_data_tbl		% table with computed gait cycle info
	end

	methods (Access = private)


		function results = func(app)

		end
	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app, caller_app)

			app.caller_app = caller_app;
			draw_foot_markers(app)
			draw_hs_to_events(app)
			compute_gait_cycle_info(app)
			app.UITable_gc_info.ColumnName = app.gc_data_tbl.Properties.VariableNames;
			app.UITable_gc_info.Data = app.gc_data_tbl;

		end

		% Close request function: HeelMarkerInsolesUIFigure
		function HeelMarkerInsolesUIFigureCloseRequest(app, event)
			% uncheck box in vicon_insoles.mlapp
			app.caller_app.ShowHeelMarkerInsoleCheckBox.Value = 0;
			delete(app)

		end

		% Button pushed function: SendtoDatabaseButton
		function SendtoDatabaseButtonPushed(app, event)
			gc_info2db(app)
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.HeelMarkerInsolesUIFigure = uifigure('Visible', 'off');
			app.HeelMarkerInsolesUIFigure.Name = 'Heel Marker & Insoles';
			app.HeelMarkerInsolesUIFigure.CloseRequestFcn = createCallbackFcn(app, @HeelMarkerInsolesUIFigureCloseRequest, true);
			app.UIAxes_inv_heel = uiaxes(app.HeelMarkerInsolesUIFigure);
			title(app.UIAxes_inv_heel, 'Involved Heel')
			zlabel(app.UIAxes_inv_heel, 'Z')
			app.UIAxes_inv_insole = uiaxes(app.HeelMarkerInsolesUIFigure);
			title(app.UIAxes_inv_insole, 'Involved Insole Force Estimate')
			zlabel(app.UIAxes_inv_insole, 'Z')
			app.UIAxes_uninv_heel = uiaxes(app.HeelMarkerInsolesUIFigure);
			title(app.UIAxes_uninv_heel, 'Uninvolved Heel')
			zlabel(app.UIAxes_uninv_heel, 'Z')
			app.UIAxes_uninv_insole = uiaxes(app.HeelMarkerInsolesUIFigure);
			title(app.UIAxes_uninv_insole, 'Uninvolved Insole Force Estimate')
			xlabel(app.UIAxes_uninv_insole, 'Time (s)')
			zlabel(app.UIAxes_uninv_insole, 'Z')
			app.UITable_gc_info = uitable(app.HeelMarkerInsolesUIFigure);
			app.UITable_gc_info.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
			app.UITable_gc_info.RowName = {};
			app.SendtoDatabaseButton = uibutton(app.HeelMarkerInsolesUIFigure, 'push');
			app.SendtoDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoDatabaseButtonPushed, true);
			app.SendtoDatabaseButton.Text = 'Send to Database';
			% Show the figure after all components are created
			app.HeelMarkerInsolesUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = foot_markers(varargin)

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.HeelMarkerInsolesUIFigure)

				% Execute the startup function
				runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
			else

				% Focus the running singleton app
				figure(runningApp.HeelMarkerInsolesUIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.HeelMarkerInsolesUIFigure)
		end
	end
end