classdef vicon_insoles < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		ViconInsoleAppUIFigure        matlab.ui.Figure
		StepInsoleDataPanel           matlab.ui.container.Panel
		DatabaseDateLabel             matlab.ui.control.Label
		InDatabaseCheckBox            matlab.ui.control.CheckBox
		ShowHeelMarkerInsoleCheckBox  matlab.ui.control.CheckBox
		AffectedLegDropDown           matlab.ui.control.DropDown
		AffectedLegDropDownLabel      matlab.ui.control.Label
		HSTOEventsPanel               matlab.ui.container.Panel
		LTOCheckBox                   matlab.ui.control.CheckBox
		LHSCheckBox                   matlab.ui.control.CheckBox
		RTOCheckBox                   matlab.ui.control.CheckBox
		RHSCheckBox                   matlab.ui.control.CheckBox
		NoEEGdataCheckBox             matlab.ui.control.CheckBox
		RightInsoleCompositeCheckBox  matlab.ui.control.CheckBox
		LeftInsoleCompositeCheckBox   matlab.ui.control.CheckBox
		NumRightToLabel               matlab.ui.control.Label
		NumRightToLabelText           matlab.ui.control.Label
		UITableRightTo                matlab.ui.control.Table
		NumRightHsLabel               matlab.ui.control.Label
		NumRightHsLabelText           matlab.ui.control.Label
		UITableRightHs                matlab.ui.control.Table
		NumLeftToLabel                matlab.ui.control.Label
		NumLeftToLabelText            matlab.ui.control.Label
		UITableLeftTo                 matlab.ui.control.Table
		NumLeftHsLabel                matlab.ui.control.Label
		NumLeftHsLabelText            matlab.ui.control.Label
		UITableLeftHs                 matlab.ui.control.Table
		UITableEegEvents              matlab.ui.control.Table
		intheEEGdataLabel             matlab.ui.control.Label
		BlockNumberEditField          matlab.ui.control.NumericEditField
		ThisvicondataisblocknumberEditFieldLabel  matlab.ui.control.Label
		ButtonSelectEegFile           matlab.ui.control.Button
		EditFieldEegFilename          matlab.ui.control.EditField
		EEGdatafileoreventfilefromanalysisnirstxtLabel  matlab.ui.control.Label
		RightInsoleRawCheckBox        matlab.ui.control.CheckBox
		LeftInsoleRawCheckBox         matlab.ui.control.CheckBox
		ButtonSelectViconFile         matlab.ui.control.Button
		h_GUIButton                   matlab.ui.control.Button
		EditFieldViconFilename        matlab.ui.control.EditField
		ViconcsvfileLabel             matlab.ui.control.Label
	end


	properties (Access = public)
		h_foot_data_fig % handle of the foot data figure
		figure_insole_left_raw % handle to left insole figure
		figure_insole_right_raw % handle to right insole figure
		figure_insole_left_composite  % handle to left insole figure
		figure_insole_right_composite % handle to right insole figure

		subject			% subject number
		session			% session (pre, mid, post, followup)
		trial_num		% vicon trial number
		side			% subjects affected leg/side

		vicon_data        % data read in from vicon csv file. See read_vicon_csv.m for the data format
		event_struct       % struct with hs & to times
		fsr_event_threshold_struct % struct with threshold used to compute events

		coeff			% struct with left and right polyval coefficients for insole calibration (conversion from V to N)
		vicon_data_unreversed  % original data saved here when insoles are l-r reversed in vicon_data

		eeg_data        % eeg data
		eeg_events      % struct with vicon_start, vicon_stop, walk, turn&stop, and rest event times

		table_walk_num  % walk number to show in the tables

		foot_marker_app	% app to display foot heel marker & compute gait cycle info
	end

	properties (Access = private)
	end
	
	methods (Access = public)
		
	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			%             app.ViconInsoleAppUIFigure.HandleVisibility = 'on';
			app.table_walk_num = 0;
		end

		% Button pushed function: h_GUIButton
		function h_GUI_export(app, event)
			assignin('base','h_gui',app)
			disp('GUI handles exported to base as h_gui')
		end

		% Button pushed function: ButtonSelectViconFile
		function ButtonSelectViconFilePushed(app, event)
			disp('Choose a Vicon data file. Preferably a _devices.csv file.')
			[fname, pname] = uigetfile('*.csv', 'Pick Vicon Data csv File');
			if ~isequal(fname,0) && ~isequal(pname,0)
				app.EditFieldViconFilename.Value = fullfile(pname, fname);

				EditFieldViconFilenameValueChanged(app)
			end

		end

		% Value changed function: EditFieldViconFilename
		function EditFieldViconFilenameValueChanged(app, event)
			value = app.EditFieldViconFilename.Value;
			h_wait = uiprogressdlg(app.ViconInsoleAppUIFigure, "Indeterminate", "on", ...
				'Message', 'Reading in Vicon data');
			if contains(lower(value), '_devices')
				app.vicon_data.devices = read_vicon_devices_csv(value);
			else
				app.vicon_data = read_vicon_csv(value);
			end
			% reset event_struct
			app.event_struct = [];

			% read in insole calibration coefficients
			h_wait.Message = 'Getting Insole Calibration';
			app.coeff = get_cal_coeff(app);
			if isempty(app.coeff)
				delete(h_wait)
				return
			end

			% if insoles are lr reversed
			if isfield(app.coeff, 'lr_reversed') && app.coeff.lr_reversed == 1
				% reverse the insole labels
				reverse_left_right_insoles(app)
			end

			% read in the threshold used to compute events, if available
			get_event_threshold_from_file(app)

			% read in events if they are available
			h_wait.Message = 'Reading previous events';
			get_events_from_default_files(app)

			% complete_event_struct(app) % this checks the event_struct for
			% times, if empty then it
			% recomputes the composite fsr voltage (open_composite_insole_fig
			% computes and converts to force est and plots) and uses that for the
			% event detection.

			% parse the filename for subject, session, trial number
			h_wait.Message = 'Parsing file name for subject, session & trial num';
			parse_filename(app)
			if isempty(app.subject) || isempty(app.session) || isempty(app.trial_num) || isnan(app.trial_num)
				msg = sprintf('subject = %s\nsession = %s\ntrial num = %d', app.subject, app.session, app.trial_num);
				uialert(app.ViconInsoleAppUIFigure, msg, 'Missing info', 'Icon', 'error')
				delete(h_wait)
				return
			end
			% get involved side from database
			h_wait.Message = 'Getting involved side from database';
			try
				app.side = find_subject_involved_side(app.subject);
			catch
				uialert(app.ViconInsoleAppUIFigure, 'Error connecting to database. Affected side not known.', ...
					'Affected side', 'Icon', 'warning')
				app.side = 'unknown';
			end
			str = app.side;
			str(1) = upper(str(1));
			app.AffectedLegDropDown.Value = str;

			% check database for gait_cyle info (step length, insole forces, etc)
			h_wait.Message = 'Checking database for step info';
			check_database_gc_info(app)

			delete(h_wait)
		end

		% Value changed function: LeftInsoleRawCheckBox
		function LeftInsoleRawCheckBoxValueChanged(app, event)
			value = app.LeftInsoleRawCheckBox.Value;
			if value
				open_insole_fig(app, 'left')
			else
				close(app.figure_insole_left_raw)
			end
		end

		% Value changed function: RightInsoleRawCheckBox
		function RightInsoleRawCheckBoxValueChanged(app, event)
			value = app.RightInsoleRawCheckBox.Value;
			if value
				open_insole_fig(app, 'right')
			else
				close(app.figure_insole_right_raw)
			end
		end

		% Button pushed function: ButtonSelectEegFile
		function ButtonSelectEegFilePushed(app, event)
			disp('Choose a EEG data vhdr file or event txt file, or in rare cases *.mat file (if events had to be recreated from xdf files)')
			[fname, pname] = uigetfile({'*.*', 'file'; '*.mat', 'mat file'; '*.vhdr', 'EEG vhdr file'; ...
				'*.txt', 'Event.txt file'; }, 'Pick EEG Data vhdr or Event txt File');
			if ~isequal(fname,0) && ~isequal(pname,0)
				app.EditFieldEegFilename.Value = fullfile(pname, fname);
				EditFieldEegFilenameValueChanged(app)
			end
		end

		% Value changed function: EditFieldEegFilename
		function EditFieldEegFilenameValueChanged(app, event)
			value = app.EditFieldEegFilename.Value;
			[pathName, fName, fExt] = fileparts(value);
			if strcmp(fExt, '.vhdr')
				app.eeg_data = pop_loadbv(pathName, [fName fExt]);
				app.eeg_data.time = (1:app.eeg_data.pnts)/app.eeg_data.srate;
				% get eeg events
				app.eeg_events = get_eeg_events(app.eeg_data);
			elseif strcmp(fExt, '.txt')
				app.eeg_events = read_events(value);
			elseif strcmp(fExt, '.xml')
				app.eeg_events = readstruct(value);
			elseif strcmp(fExt, '.mat')
				tmp = load(fullfile(pathName,[fName fExt]));
				app.eeg_data = tmp.EEG;
				app.eeg_data.time = (1:app.eeg_data.pnts)/app.eeg_data.srate;
				% get eeg events
				app.eeg_events = get_eeg_events(app.eeg_data);
			else
				error('Unknown eeg or event file: %s', value)
			end
			% put event info into the table
			update_eeg_event_table(app)

		end

		% Value changed function: BlockNumberEditField
		function BlockNumberEditFieldValueChanged(app, event)
			%             value = app.BlockNumberEditField.Value;
			block_num = app.BlockNumberEditField.Value;
			if block_num > 0 && block_num <= length(app.eeg_events.vicon_start)
				% put event info into the table
				update_eeg_event_table(app)
				% remove any event lines
				h_lines = findobj('-regexp', 'Tag', 'line_eeg_evt_.*');
				if ~isempty(h_lines)
					delete(h_lines)
					% uncheck the show checkbox
					h_chk = findobj('Tag', 'show_eeg_events_chkbx');
					for cnt = 1:length(h_chk)
						h_chk(cnt).Value = 0;
					end
				end
			else
				errmsg = sprintf('Block number must be between 1 and %d.', ...
					length(app.eeg_events.vicon_start));
				errordlg(errmsg, 'Block number error')
			end
		end

		% Cell selection callback: UITableEegEvents
		function UITableEegEventsCellSelection(app, event)
			indices = event.Indices;
			app.table_walk_num = indices(1);
			update_evt_table(app,'LeftHs')
			update_evt_table(app,'LeftTo')
			update_evt_table(app,'RightHs')
			update_evt_table(app,'RightTo')
		end

		% Cell edit callback: UITableEegEvents
		function UITableEegEventsCellEdit(app, event)
			walk_num = event.Indices(1); % changed row = walk number
			newData = event.NewData;     % new value for turn & stop
			% find the index into the eeg_events struct
			eeg_block = app.BlockNumberEditField.Value;
			vicon_start = app.eeg_events.vicon_start(eeg_block);
			vicon_stop  = app.eeg_events.vicon_stop(eeg_block);
			block_eeg_evt_inds = find(app.eeg_events.turn_stop > vicon_start & app.eeg_events.turn_stop < vicon_stop);
			change_data_ind = block_eeg_evt_inds(walk_num);
			% update the eeg event struct turn_stop
			app.eeg_events.turn_stop(change_data_ind) = newData + vicon_start;
			% remove existing event lines
			h_lines = findobj('-regexp', 'Tag', 'line_eeg_evt_.*');
			if ~isempty(h_lines)
				delete(h_lines);
			end
			% redraw if any are being shown
			h_show = findobj('Tag', 'show_eeg_events_chkbx');
			for h_cnt = 1:length(h_show)
				if get(h_show(h_cnt), 'Value') == 1
					pb_show_eeg_events_callback(h_show(h_cnt), [], app)
				end
			end
		end

		% Value changed function: LeftInsoleCompositeCheckBox
		function LeftInsoleCompositeCheckBoxValueChanged(app, event)
			value = app.LeftInsoleCompositeCheckBox.Value;
			if value
				open_composite_insole_fig(app, 'left')
			else
				close(app.figure_insole_left_composite)
			end
		end

		% Value changed function: RightInsoleCompositeCheckBox
		function RightInsoleCompositeCheckBoxValueChanged(app, event)
			value = app.RightInsoleCompositeCheckBox.Value;
			if value
				open_composite_insole_fig(app, 'right')
			else
				close(app.figure_insole_right_composite)
			end
		end

		% Value changed function: NoEEGdataCheckBox
		function NoEEGdataCheckBoxValueChanged(app, event)
			value = app.NoEEGdataCheckBox.Value;
			if value == 1
				% there is EEG data with walk, turn & stop, rest events
				% disable the EEG items
				app.ButtonSelectEegFile.Enable = 'off';
				app.EditFieldEegFilename.Enable = 'off';
				app.BlockNumberEditField.Enable = 'off';
				app.intheEEGdataLabel.Enable = 'off';
				app.UITableEegEvents.Enable = 'off';
			else
				% enable the EEG items
				app.ButtonSelectEegFile.Enable = 'on';
				app.EditFieldEegFilename.Enable = 'on';
				app.BlockNumberEditField.Enable = 'on';
				app.intheEEGdataLabel.Enable = 'on';
				app.UITableEegEvents.Enable = 'on';
			end
		end

		% Value changed function: ShowHeelMarkerInsoleCheckBox
		function ShowHeelMarkerInsoleCheckBoxValueChanged(app, event)
			value = app.ShowHeelMarkerInsoleCheckBox.Value;
			if value == 1
				app.foot_marker_app = foot_markers(app);
			else
				delete(app.foot_marker_app)
			end
		end

		% Close request function: ViconInsoleAppUIFigure
		function ViconInsoleAppUIFigureCloseRequest(app, event)
			% delete foot_marker app if present
			delete(app.foot_marker_app)
			delete(app)

		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.ViconInsoleAppUIFigure = uifigure('Visible', 'off');
			app.ViconInsoleAppUIFigure.Name = 'Vicon Insole App';
			app.ViconInsoleAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @ViconInsoleAppUIFigureCloseRequest, true);
			app.ViconcsvfileLabel = uilabel(app.ViconInsoleAppUIFigure);
			app.ViconcsvfileLabel.Text = 'Vicon csv file:';
			app.EditFieldViconFilename = uieditfield(app.ViconInsoleAppUIFigure, 'text');
			app.EditFieldViconFilename.ValueChangedFcn = createCallbackFcn(app, @EditFieldViconFilenameValueChanged, true);
			app.h_GUIButton = uibutton(app.ViconInsoleAppUIFigure, 'push');
			app.h_GUIButton.ButtonPushedFcn = createCallbackFcn(app, @h_GUI_export, true);
			app.h_GUIButton.Text = 'h_GUI';
			app.ButtonSelectViconFile = uibutton(app.ViconInsoleAppUIFigure, 'push');
			app.ButtonSelectViconFile.ButtonPushedFcn = createCallbackFcn(app, @ButtonSelectViconFilePushed, true);
			app.ButtonSelectViconFile.Text = 'Select';
			app.LeftInsoleRawCheckBox = uicheckbox(app.ViconInsoleAppUIFigure);
			app.LeftInsoleRawCheckBox.ValueChangedFcn = createCallbackFcn(app, @LeftInsoleRawCheckBoxValueChanged, true);
			app.LeftInsoleRawCheckBox.Text = 'Left Insole Raw Data';
			app.RightInsoleRawCheckBox = uicheckbox(app.ViconInsoleAppUIFigure);
			app.RightInsoleRawCheckBox.ValueChangedFcn = createCallbackFcn(app, @RightInsoleRawCheckBoxValueChanged, true);
			app.RightInsoleRawCheckBox.Text = 'Right Insole Raw Data';
			app.EEGdatafileoreventfilefromanalysisnirstxtLabel = uilabel(app.ViconInsoleAppUIFigure);
			app.EEGdatafileoreventfilefromanalysisnirstxtLabel.Text = 'EEG data file or event file from analysis/nirs/*.txt:';
			app.EditFieldEegFilename = uieditfield(app.ViconInsoleAppUIFigure, 'text');
			app.EditFieldEegFilename.ValueChangedFcn = createCallbackFcn(app, @EditFieldEegFilenameValueChanged, true);
			app.ButtonSelectEegFile = uibutton(app.ViconInsoleAppUIFigure, 'push');
			app.ButtonSelectEegFile.ButtonPushedFcn = createCallbackFcn(app, @ButtonSelectEegFilePushed, true);
			app.ButtonSelectEegFile.Text = 'Select';
			app.ThisvicondataisblocknumberEditFieldLabel = uilabel(app.ViconInsoleAppUIFigure);
			app.ThisvicondataisblocknumberEditFieldLabel.Text = 'This vicon data is block number';
			app.BlockNumberEditField = uieditfield(app.ViconInsoleAppUIFigure, 'numeric');
			app.BlockNumberEditField.ValueChangedFcn = createCallbackFcn(app, @BlockNumberEditFieldValueChanged, true);
			app.intheEEGdataLabel = uilabel(app.ViconInsoleAppUIFigure);
			app.intheEEGdataLabel.Text = 'in the EEG data.';
			app.UITableEegEvents = uitable(app.ViconInsoleAppUIFigure);
			app.UITableEegEvents.ColumnName = {'Num'; 'Walk'; 'Turn & Stop'; 'Rest'};
			app.UITableEegEvents.RowName = {};
			app.UITableEegEvents.ColumnEditable = [false false true false];
			app.UITableEegEvents.CellEditCallback = createCallbackFcn(app, @UITableEegEventsCellEdit, true);
			app.UITableEegEvents.CellSelectionCallback = createCallbackFcn(app, @UITableEegEventsCellSelection, true);
			app.UITableLeftHs = uitable(app.ViconInsoleAppUIFigure);
			app.UITableLeftHs.ColumnName = {'Heel Strike Time'};
			app.UITableLeftHs.RowName = {};
			app.UITableLeftHs.ColumnSortable = true;
			app.NumLeftHsLabelText = uilabel(app.ViconInsoleAppUIFigure);
			app.NumLeftHsLabelText.Text = 'Num HS =';
			app.NumLeftHsLabel = uilabel(app.ViconInsoleAppUIFigure);
			app.NumLeftHsLabel.Text = '';
			app.UITableLeftTo = uitable(app.ViconInsoleAppUIFigure);
			app.UITableLeftTo.ColumnName = {'Toe Off Time'};
			app.UITableLeftTo.RowName = {};
			app.NumLeftToLabelText = uilabel(app.ViconInsoleAppUIFigure);
			app.NumLeftToLabelText.Text = 'Num TO =';
			app.NumLeftToLabel = uilabel(app.ViconInsoleAppUIFigure);
			app.NumLeftToLabel.Text = '';
			app.UITableRightHs = uitable(app.ViconInsoleAppUIFigure);
			app.UITableRightHs.ColumnName = {'Heel Strike Time'};
			app.UITableRightHs.RowName = {};
			app.UITableRightHs.ColumnSortable = true;
			app.NumRightHsLabelText = uilabel(app.ViconInsoleAppUIFigure);
			app.NumRightHsLabelText.Text = 'Num HS =';
			app.NumRightHsLabel = uilabel(app.ViconInsoleAppUIFigure);
			app.NumRightHsLabel.Text = '';
			app.UITableRightTo = uitable(app.ViconInsoleAppUIFigure);
			app.UITableRightTo.ColumnName = {'Toe Off Time'};
			app.UITableRightTo.RowName = {};
			app.NumRightToLabelText = uilabel(app.ViconInsoleAppUIFigure);
			app.NumRightToLabelText.Text = 'Num TO =';
			app.NumRightToLabel = uilabel(app.ViconInsoleAppUIFigure);
			app.NumRightToLabel.Text = '';
			app.LeftInsoleCompositeCheckBox = uicheckbox(app.ViconInsoleAppUIFigure);
			app.LeftInsoleCompositeCheckBox.ValueChangedFcn = createCallbackFcn(app, @LeftInsoleCompositeCheckBoxValueChanged, true);
			app.LeftInsoleCompositeCheckBox.Text = 'Left Insole Composite Data';
			app.RightInsoleCompositeCheckBox = uicheckbox(app.ViconInsoleAppUIFigure);
			app.RightInsoleCompositeCheckBox.ValueChangedFcn = createCallbackFcn(app, @RightInsoleCompositeCheckBoxValueChanged, true);
			app.RightInsoleCompositeCheckBox.Text = 'Right Insole Composite Data';
			app.NoEEGdataCheckBox = uicheckbox(app.ViconInsoleAppUIFigure);
			app.NoEEGdataCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoEEGdataCheckBoxValueChanged, true);
			app.NoEEGdataCheckBox.Text = 'No EEG data';
			app.HSTOEventsPanel = uipanel(app.ViconInsoleAppUIFigure);
			app.HSTOEventsPanel.Title = 'HS & TO Events';
			app.RHSCheckBox = uicheckbox(app.HSTOEventsPanel);
			app.RHSCheckBox.Text = 'RHS';
			app.RTOCheckBox = uicheckbox(app.HSTOEventsPanel);
			app.RTOCheckBox.Text = 'RTO';
			app.LHSCheckBox = uicheckbox(app.HSTOEventsPanel);
			app.LHSCheckBox.Text = 'LHS';
			app.LTOCheckBox = uicheckbox(app.HSTOEventsPanel);
			app.LTOCheckBox.Text = 'LTO';
			app.AffectedLegDropDownLabel = uilabel(app.ViconInsoleAppUIFigure);
			app.AffectedLegDropDownLabel.Text = 'Affected Leg:';
			app.AffectedLegDropDown = uidropdown(app.ViconInsoleAppUIFigure);
			app.AffectedLegDropDown.Items = {'Unknown', 'Right', 'Left'};
			app.AffectedLegDropDown.Tooltip = {'Affected side/leg looked up in the database.'};
			app.AffectedLegDropDown.Value = 'Unknown';
			app.StepInsoleDataPanel = uipanel(app.ViconInsoleAppUIFigure);
			app.StepInsoleDataPanel.Title = 'Step & Insole Data';
			app.ShowHeelMarkerInsoleCheckBox = uicheckbox(app.StepInsoleDataPanel);
			app.ShowHeelMarkerInsoleCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowHeelMarkerInsoleCheckBoxValueChanged, true);
			app.ShowHeelMarkerInsoleCheckBox.Text = 'Show Heel Marker & Insole';
			app.InDatabaseCheckBox = uicheckbox(app.StepInsoleDataPanel);
			app.InDatabaseCheckBox.Text = 'In Database';
			app.DatabaseDateLabel = uilabel(app.StepInsoleDataPanel);
			app.DatabaseDateLabel.Text = '2023-00-00';
			% Show the figure after all components are created
			app.ViconInsoleAppUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = vicon_insoles

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.ViconInsoleAppUIFigure)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.ViconInsoleAppUIFigure)
		end
	end
end